classdef projetoControle2Jean < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PolosLabel                 matlab.ui.control.Label
        ZerosLabel                 matlab.ui.control.Label
        LimparButton               matlab.ui.control.Button
        CalcularButton             matlab.ui.control.Button
        SelecioneDropDown          matlab.ui.control.DropDown
        SelecioneDropDownLabel     matlab.ui.control.Label
        DenominadorEditField       matlab.ui.control.EditField
        DenominadorEditFieldLabel  matlab.ui.control.Label
        NumeradorEditField         matlab.ui.control.EditField
        NumeradorEditFieldLabel    matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
          % Obter entradas do usuário
            Numerador = str2num(app.NumeradorEditField.Value);
            Denominador = str2num(app.DenominadorEditField.Value);
            
            % Verificar se o denominador é zero ou vazio
            if isempty(Denominador) || all(Denominador == 0)
                uialert(app.UIFigure, 'O denominador não pode ser zero ou vazio.', 'Erro');
                return;
            end
            
            % Criar a função de transferência
            sys = tf(Numerador, Denominador);

            % Limpar o gráfico
            cla(app.UIAxes2);

            % Obter polos e zeros do sistema
                    [polos, zeros] = pzmap(sys);
                    
                    % Exibir polos e zeros nos labels
                    app.PolosLabel.Text = ['Polos: ', num2str(polos.')];
                    app.ZerosLabel.Text = ['Zeros: ', num2str(zeros.')];

            % Alternar entre as funções selecionadas
            switch app.SelecioneDropDown.Value
                case 'Lugar Geométrico das Raizes'
                    rlocus(app.UIAxes2, sys);
                    title(app.UIAxes2, 'Lugar Geométrico das Raízes');
                    
                case 'Resposta ao Degrau'
                    step(app.UIAxes2, sys);
                    title(app.UIAxes2, 'Resposta ao Degrau');
                    
                case 'Resposta a Rampa'
                    % Criar um sistema de rampa
                    tempo_simulacao = 0:0.01:20; 
                    entrada_rampa = tempo_simulacao; 
                    
                    % Simule a resposta do sistema para a entrada rampa
                    [saida_rampa, tempo_resposta] = lsim(sys, entrada_rampa, tempo_simulacao);
                    
                    % Plote a resposta a rampa no UIAxes
                    plot(app.UIAxes2, tempo_resposta, saida_rampa);
                    title(app.UIAxes2, 'Resposta a Rampa');

                case 'Resposta a Parabola'
                    % Criar um sistema de parábola
                    tempo_simulacao = 0:0.01:20; 
                    entrada_parabola = (tempo_simulacao.^2) / 2;
                    
                    % Simule a resposta do sistema para a entrada parabólica
                    [saida_parabola, tempo_resposta] = lsim(sys, entrada_parabola, tempo_simulacao);
                    
                    % Plote a resposta à parábola no UIAxes
                    plot(app.UIAxes2, tempo_resposta, saida_parabola);
                    title(app.UIAxes2, 'Resposta a Parabola');
            
            end
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
          % Limpar as entradas
            app.NumeradorEditField.Value = '';
            app.DenominadorEditField.Value = '';

            % Limpar o gráfico
            cla(app.UIAxes2);

            % Redefinir o título do gráfico para "Gráfico"
            title(app.UIAxes2, 'Grafico');

            % Limpar labels de polos e zeros
            app.PolosLabel.Text = 'Polos:';
            app.ZerosLabel.Text = 'Zeros:';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grafico')
            xlabel(app.UIAxes2, 'Re')
            ylabel(app.UIAxes2, 'Im')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.NumeradorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeradorEditFieldLabel.Text = 'Numerador';
            app.NumeradorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominadorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominadorEditFieldLabel.Text = 'Denominador';
            app.DenominadorEditField = uieditfield(app.UIFigure, 'text');
            app.SelecioneDropDownLabel = uilabel(app.UIFigure);
            app.SelecioneDropDownLabel.Text = 'Selecione:';
            app.SelecioneDropDown = uidropdown(app.UIFigure);
            app.SelecioneDropDown.Items = {'Lugar Geométrico das Raizes', 'Resposta ao Degrau', 'Resposta a Rampa', 'Resposta a Parabola'};
            app.SelecioneDropDown.Value = 'Lugar Geométrico das Raizes';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.ZerosLabel = uilabel(app.UIFigure);
            app.ZerosLabel.Text = 'ZerosLabel';
            app.PolosLabel = uilabel(app.UIFigure);
            app.PolosLabel.Text = 'PolosLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projetoControle2Jean

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end